classdef ImageEdit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ImageAxes                 matlab.ui.control.UIAxes
        GreenAxes                 matlab.ui.control.UIAxes
        BlueAxes                  matlab.ui.control.UIAxes
        LoadButton                matlab.ui.control.Button
        RedAxes                   matlab.ui.control.UIAxes
        BrigSliderLabel           matlab.ui.control.Label
        BrigSlider                matlab.ui.control.Slider
        ContSliderLabel           matlab.ui.control.Label
        ContSlider                matlab.ui.control.Slider
        crescentSpinnerLabel      matlab.ui.control.Label
        crescentSpinner           matlab.ui.control.Spinner
        DrawRandomCrescentButton  matlab.ui.control.Button
        MedianFilterButton        matlab.ui.control.StateButton
        HminSpinnerLabel          matlab.ui.control.Label
        HminSpinner               matlab.ui.control.Spinner
        SminSpinnerLabel          matlab.ui.control.Label
        SminSpinner               matlab.ui.control.Spinner
        VminSpinnerLabel          matlab.ui.control.Label
        VminSpinner               matlab.ui.control.Spinner
        HmaxSpinnerLabel          matlab.ui.control.Label
        HmaxSpinner               matlab.ui.control.Spinner
        SmaxSpinnerLabel          matlab.ui.control.Label
        SmaxSpinner               matlab.ui.control.Spinner
        VmaxSpinnerLabel          matlab.ui.control.Label
        VmaxSpinner               matlab.ui.control.Spinner
        RSpinnerLabel             matlab.ui.control.Label
        RSpinner                  matlab.ui.control.Spinner
        GSpinnerLabel             matlab.ui.control.Label
        GSpinner                  matlab.ui.control.Spinner
        BSpinnerLabel             matlab.ui.control.Label
        BSpinner                  matlab.ui.control.Spinner
        ChangeColorButton         matlab.ui.control.Button
        AverageFilterButton       matlab.ui.control.StateButton
        K1ConvButton              matlab.ui.control.StateButton
        HistEqButton              matlab.ui.control.StateButton
    end

    properties (Access = public)
        im; % vstupny obrazok
        im_ones;
        im_hsv;
        im_rgb;
        crescent;
        im_BW;
        im_maskedRGBImage;
        % update pre vstupny obrazok input = upraveny;
        % Load
        imageList;
        fullPath;
        fullfile;
        Pathname;
        Filename;
        original;
        hsv_space;
        % Prevody
        inputImage_bw;
        inputImage_binarny;
        inputImage_index;
        % Segmetation
        im_r;
        im_g;
        im_b;
        rgb_image;
        im_br;
        im_selcolor;
        im_med;
        im_avg;
        im_k;
        im_hist;
        RGB2;
        inputImage_crop_image;
        inputImage_bitDepth;
        img2;
        sliderValue;
        inputImage_red_value;
        inputImage_background;
        %jas, kontrast
        im_contrast;
        inputImage_histeq;
        inputImage_auto_contrast;
        inputImage_gamma_correction;
        % ostatne
        valueRed2;
        inputImage2;
        lower;
        upper;
        lower2
        upper2;
        rcon;
        originalImage;
        backgroundImage;
        h;
        d;
        val;
        rgb;
        manualDetectSlider;
        
    end
    
    
    methods (Access = private)
        
        function updateimage(app,imagefile)
            
            % For corn.tif, read the second image in the file
            if strcmp(imagefile,'corn.tif')
                app.im = imread('corn.tif', 2);
            else
                try
                    app.im = imread(imagefile);
                catch
                    try
                        app.im=imagefile;
                    catch ME
                        % If problem reading image, display error message
                        uialert(app.UIFigure, ME.message, 'Image Error');
                        return;
                    end
                end
            end
            
            % Create histograms based on number of color channels
            switch size(app.im,3)
                case 1
                    % Display the grayscale image
                    imagesc(app.ImageAxes,app.im);
                    
                    % Plot all histograms with the same data for grayscale
                    histr = histogram(app.RedAxes, app.im, 'FaceColor',[1 0 0],'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, app.im, 'FaceColor',[0 1 0],'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, app.im, 'FaceColor',[0 0 1],'EdgeColor', 'none');
                    
                case 3
                    % Display the truecolor image
                    imagesc(app.ImageAxes,app.im);
                    
                    % Plot the histograms
                    histr = histogram(app.RedAxes, app.im(:,:,1), 'FaceColor', [1 0 0], 'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, app.im(:,:,2), 'FaceColor', [0 1 0], 'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, app.im(:,:,3), 'FaceColor', [0 0 1], 'EdgeColor', 'none');
                    
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
            % Get largest bin count
            maxr = max(histr.BinCounts);
            maxg = max(histg.BinCounts);
            maxb = max(histb.BinCounts);
            maxcount = max([maxr maxg maxb]);
            
            % Set y axes limits based on largest bin count
            app.RedAxes.YLim = [0 maxcount];
            app.RedAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
            app.GreenAxes.YLim = [0 maxcount];
            app.GreenAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
            app.BlueAxes.YLim = [0 maxcount];
            app.BlueAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
            app.im_hsv=rgb2hsv(app.im);
            
        end
        
        function updateHistogram(app,im)
            switch size(im,3)
                case 1
                    % Display the grayscale image
                    imagesc(app.ImageAxes,im);
                    
                    % Plot all histograms with the same data for grayscale
                    histr = histogram(app.RedAxes, im, 'FaceColor',[1 0 0],'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im, 'FaceColor',[0 1 0],'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im, 'FaceColor',[0 0 1],'EdgeColor', 'none');
                    
                case 3
                    % Display the truecolor image
                    imagesc(app.ImageAxes,im);
                    
                    % Plot the histograms
                    histr = histogram(app.RedAxes, im(:,:,1), 'FaceColor', [1 0 0], 'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im(:,:,2), 'FaceColor', [0 1 0], 'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im(:,:,3), 'FaceColor', [0 0 1], 'EdgeColor', 'none');
                    
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
            % Get largest bin count
            maxr = max(histr.BinCounts);
            maxg = max(histg.BinCounts);
            maxb = max(histb.BinCounts);
            maxcount = max([maxr maxg maxb]);
            
            % Set y axes limits based on largest bin count
            app.RedAxes.YLim = [0 maxcount];
            app.RedAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
            app.GreenAxes.YLim = [0 maxcount];
            app.GreenAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
            app.BlueAxes.YLim = [0 maxcount];
            app.BlueAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
            app.im_selcolor = zeros(size(app.im, 1), size(app.im, 2));
        end
        
        function [I] = circle(~,I,rand,x,y,r)
            [rows,cols,~]=size(I);
            
            for x_=1:rows
                for y_=1:cols
                    if (x_-x)^2+(y_-y)^2<=r^2
                        I(x_,y_)=1;
                        I(x_+rand,y_+rand)=0;
                    end
                end
            end
            return
        end
        
        function [B] = medfilter(~,A)
            %DEFINE THE WINDOW SIZE MXN
            M=9;
            N=9;
            
            %PAD THE MATRIX WITH ZEROS ON ALL SIDES
            modifyA=padarray(A,[floor(M/2),floor(N/2)]);
            
            B = zeros([size(A,1) size(A,2)]);
            med_indx = round((M*N)/2); %MEDIAN INDEX
            for i = 1:size(modifyA,1)-(M-1)
                for j = 1:size(modifyA,2)-(N-1)
                    temp = modifyA(i:i+(M-1),j:j+(N-1),:);
                    %RED,GREEN AND BLUE CHANNELS ARE TRAVERSED SEPARATELY
                    for k = 1:3
                        tmp = temp(:,:,k);
                        B(i,j,k) = median(tmp(:));
                    end
                end
            end
            
            
            %CONVERT THE IMAGE TO UINT8 FORMAT.
            B = uint8(B);
            return
        end
        
        function [maskedRGBImage] = createMask(~, RGB,Hmin,Hmax,Smin,Smax,Vmin,Vmax)
            %createMask  Threshold RGB image using auto-generated code from colorThresholder app.
            %  [BW,MASKEDRGBIMAGE] = createMask(RGB) thresholds image RGB using
            %  auto-generated code from the colorThresholder app. The colorspace and
            %  range for each channel of the colorspace were set within the app. The
            %  segmentation mask is returned in BW, and a composite of the mask and
            %  original RGB images is returned in maskedRGBImage.
            
            % Auto-generated by colorThresholder app on 22-Nov-2019
            %------------------------------------------------------
            
            
            % Convert RGB image to chosen color space
            I = rgb2hsv(RGB);
            
            % Define thresholds for channel 1 based on histogram settings
            channel1Min = Hmin/360;
            channel1Max = Hmax/360;
            
            % Define thresholds for channel 2 based on histogram settings
            channel2Min = Smin/360;
            channel2Max = Smax/360;
            
            % Define thresholds for channel 3 based on histogram settings
            channel3Min = Vmin/360;
            channel3Max = Vmax/360;
            
            % Create mask based on chosen histogram thresholds
            sliderBW = (I(:,:,1) >= channel1Min ) & (I(:,:,1) <= channel1Max) & ...
                (I(:,:,2) >= channel2Min ) & (I(:,:,2) <= channel2Max) & ...
                (I(:,:,3) >= channel3Min ) & (I(:,:,3) <= channel3Max);
            BW = sliderBW;
            app.im_BW=BW;
            % Initialize output masked image based on input image.
            maskedRGBImage = RGB;
            
            % Set background pixels where BW is false to zero.
            maskedRGBImage(repmat(~BW,[1 1 3])) = 0;
            %app.im_maskedRGBImage=maskedRGBImage;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            
            % Update the image and histograms
            updateimage(app, 'peppers.png');
        end

        % Callback function
        function DropDownValueChanged(app, event)
            
            % Update the image and histograms
            updateimage(app, app.DropDown.Value);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                fname = [p f];
                updateimage(app, fname);
            end
        end

        % Callback function
        function HSliderValueChanged(app, event)
            
            [~,~,D] = size(app.im);
            if D==3
                h_value = app.HSlider.Value;
                
                app.rcon=cat(3,h_value,app.SSlider.Value,app.VSlider.Value);
                %app.im_r=r1;
                %app.im_g=g;
                %app.im_b=b;
                app.im_rgb=uint8(hsv2rgb(app.rcon));
                %updateHistogram(app,app.im_rgb);
                imagesc(app.ImageAxes,app.im_rgb);
            else
                msgbox('This feature only available for RGB images','Action crashed','error');
            end
        end

        % Callback function
        function SSliderValueChanged(app, event)
            s_value = app.SSlider.Value;
            
            r=app.im_r;
            g=app.im_g;
            b=app.im_b;
            %app.im_selcolor = zeros(size(app.im, 1), size(app.im, 2));
            g1=g+green_value;
            %app.rcon=cat(3,r,g1,b);
            %app.im_r=r;
            %app.im_g=g1;
            %app.im_b=b;
            %updateHistogram(app,app.rcon);
            %imagesc(app.ImageAxes,app.rcon);
            %imshow(app.rcon, 'Parent', app.ImageAxes);
            
        end

        % Callback function
        function VSliderValueChanged(app, event)
            v_value = app.VSlider.Value;
            
            r=app.im_r;
            g=app.im_g;
            b=app.im_b;
            %app.im_selcolor = zeros(size(app.im, 1), size(app.im, 2));
            b1=b+blue_value;
            %app.rcon=cat(3,r,g,b1);
            %app.im_r=r;
            %app.im_g=g;
            %app.im_b=b1;
            %updateHistogram(app,app.rcon);
            %imagesc(app.ImageAxes,app.rcon);
            %imshow(app.rcon, 'Parent', app.ImageAxes);
            
        end

        % Value changed function: BrigSlider
        function BrigSliderValueChanged(app, event)
            value_br = app.BrigSlider.Value;
            app.im_br = app.im+value_br;
            %updateimage(app, app.im_br);
            updateHistogram(app,app.im_br);
            imagesc(app.ImageAxes,app.im_br);
            %imshow(app.im_br, 'Parent', app.ImageAxes);
        end

        % Value changed function: ContSlider
        function ContSliderValueChanged(app, event)
            value_contrast = app.ContSlider.Value;
            app.im_contrast=app.im.*value_contrast;
            updateHistogram(app,app.im_contrast);
            imagesc(app.ImageAxes,app.im_contrast);
        end

        % Button pushed function: DrawRandomCrescentButton
        function DrawRandomCrescentButtonPushed(app, event)
            I=zeros(1080,1920);
            app.crescent=I;
            [rows,cols,~]=size(I);
            for i=1:app.crescentSpinner.Value
                K=app.circle(I,randi([-10,-3]),randi([50,rows-50]),randi([50,cols-50]),randi([15,50]));
                app.crescent=imadd(app.crescent,K);
            end
            imagesc(app.ImageAxes,app.crescent);
            updateHistogram(app,app.crescent);
        end

        % Value changed function: MedianFilterButton
        function MedianFilterButtonValueChanged(app, event)
            value = app.MedianFilterButton.Value;
            if value
                app.im_med=app.medfilter(app.im);
                imagesc(app.ImageAxes,app.im_med);
                updateHistogram(app,app.im_med);
            else
                imagesc(app.ImageAxes,app.im);
                updateHistogram(app,app.im);
            end
        end

        % Value changed function: HminSpinner
        function HminSpinnerValueChanged(app, event)
            value = app.HminSpinner.Value;
            app.ChangeColorButton.Enable=true;
            app.im_maskedRGBImage=app.createMask(app.im,value,app.HmaxSpinner.Value,app.SminSpinner.Value,app.SmaxSpinner.Value,app.VminSpinner.Value,app.VmaxSpinner.Value);
            imagesc(app.ImageAxes,app.im_maskedRGBImage);
            updateHistogram(app,app.im_maskedRGBImage);
        end

        % Value changed function: SminSpinner
        function SminSpinnerValueChanged(app, event)
            value = app.SminSpinner.Value;
            app.ChangeColorButton.Enable=true;
            app.im_maskedRGBImage=app.createMask(app.im,app.HminSpinner.Value,app.HmaxSpinner.Value,value,app.SmaxSpinner.Value,app.VminSpinner.Value,app.VmaxSpinner.Value);
            imagesc(app.ImageAxes,app.im_maskedRGBImage);
            updateHistogram(app,app.im_maskedRGBImage);
        end

        % Value changed function: VminSpinner
        function VminSpinnerValueChanged(app, event)
            app.ChangeColorButton.Enable=true;
            app.im_maskedRGBImage=app.createMask(app.im,app.HminSpinner.Value,app.HmaxSpinner.Value,app.SminSpinner.Value,app.SmaxSpinner.Value,value,app.VmaxSpinner.Value);
            imagesc(app.ImageAxes,app.im_maskedRGBImage);
            updateHistogram(app,app.im_maskedRGBImage);
        end

        % Value changed function: HmaxSpinner
        function HmaxSpinnerValueChanged(app, event)
            value = app.HmaxSpinner.Value;
            app.ChangeColorButton.Enable=true;
            app.im_maskedRGBImage=app.createMask(app.im,app.HminSpinner.Value,value,app.SminSpinner.Value,app.SmaxSpinner.Value,app.VminSpinner.Value,app.VmaxSpinner.Value);
            imagesc(app.ImageAxes,app.im_maskedRGBImage);
            updateHistogram(app,app.im_maskedRGBImage);
        end

        % Value changed function: SmaxSpinner
        function SmaxSpinnerValueChanged(app, event)
            value = app.SmaxSpinner.Value;
            app.ChangeColorButton.Enable=true;
            app.im_maskedRGBImage=app.createMask(app.im,app.HminSpinner.Value,app.HmaxSpinner.Value,app.SminSpinner.Value,value,app.VminSpinner.Value,app.VmaxSpinner.Value);
            imagesc(app.ImageAxes,app.im_maskedRGBImage);
            updateHistogram(app,app.im_maskedRGBImage);
        end

        % Value changed function: VmaxSpinner
        function VmaxSpinnerValueChanged(app, event)
            value = app.VmaxSpinner.Value;
            app.ChangeColorButton.Enable=true;
            app.im_maskedRGBImage=app.createMask(app.im,app.HminSpinner.Value,app.HmaxSpinner.Value,app.SminSpinner.Value,app.SmaxSpinner.Value,app.VminSpinner.Value,value);
            imagesc(app.ImageAxes,app.im_maskedRGBImage);
            updateHistogram(app,app.im_maskedRGBImage);
        end

        % Button pushed function: ChangeColorButton
        function ChangeColorButtonPushed(app, event)
            redChannel = app.im_maskedRGBImage(:, :, 1);
            greenChannel = app.im_maskedRGBImage(:, :, 2);
            blueChannel = app.im_maskedRGBImage(:, :, 3);
            % Find pixels that are pure black - black in all 3 channels.
            blackPixels = redChannel == 0 & greenChannel  == 0 & blueChannel  == 0;
            % Make color rgb val
            redChannel(blackPixels) = app.RSpinner.Value;
            greenChannel(blackPixels) = app.GSpinner.Value;
            blueChannel(blackPixels) = app.BSpinner.Value;
            % Recombine separate color channels into a single, true color RGB image.
            app.im_maskedRGBImage = cat(3, redChannel, greenChannel, blueChannel);
            imagesc(app.ImageAxes,app.im_maskedRGBImage);
            updateHistogram(app,app.im_maskedRGBImage);
        end

        % Value changed function: AverageFilterButton
        function AverageFilterButtonValueChanged(app, event)
            K2=(1/81)*ones(9);
            value = app.AverageFilterButton.Value;
            if value
                app.im_avg=conv2(rgb2gray(app.im),K2);
                imagesc(app.ImageAxes,app.im_avg);
                updateHistogram(app,app.im_avg);
            else
                imagesc(app.ImageAxes,app.im);
                updateHistogram(app,app.im);
            end
        end

        % Value changed function: K1ConvButton
        function K1ConvButtonValueChanged(app, event)
            K1=[-4 0 0;0 0 0;0 0 4];
            value = app.K1ConvButton.Value;
            if value
                app.im_k=conv2(rgb2gray(app.im),K1);
                imagesc(app.ImageAxes,app.im_k);
                updateHistogram(app,app.im_k);
            else
                imagesc(app.ImageAxes,app.im);
                updateHistogram(app,app.im);
            end
        end

        % Value changed function: HistEqButton
        function HistEqButtonValueChanged(app, event)
            value = app.HistEqButton.Value;
            if value
                app.im_hist=histeq(app.im);
                imagesc(app.ImageAxes,app.im_hist);
                updateHistogram(app,app.im_hist);
            else
                imagesc(app.ImageAxes,app.im);
                updateHistogram(app,app.im);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.GreenAxes = uiaxes(app.UIFigure);
            title(app.GreenAxes, 'Green')
            xlabel(app.GreenAxes, 'Intensity')
            ylabel(app.GreenAxes, 'Pixels')
            app.GreenAxes.XLim = [0 255];
            app.GreenAxes.XTick = [0 128 255];
            app.BlueAxes = uiaxes(app.UIFigure);
            title(app.BlueAxes, 'Blue')
            xlabel(app.BlueAxes, 'Intensity')
            ylabel(app.BlueAxes, 'Pixels')
            app.BlueAxes.XLim = [0 255];
            app.BlueAxes.XTick = [0 128 255];
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Custom Image';
            app.RedAxes = uiaxes(app.UIFigure);
            title(app.RedAxes, 'Red')
            xlabel(app.RedAxes, 'Intensity')
            ylabel(app.RedAxes, 'Pixels')
            app.RedAxes.XLim = [0 255];
            app.RedAxes.XTick = [0 128 255];
            app.BrigSliderLabel = uilabel(app.UIFigure);
            app.BrigSliderLabel.Text = 'Brig';
            app.BrigSlider = uislider(app.UIFigure);
            app.BrigSlider.Limits = [-100 100];
            app.BrigSlider.MajorTicks = [-100 -75 -50 -25 0 25 50 75 100];
            app.BrigSlider.ValueChangedFcn = createCallbackFcn(app, @BrigSliderValueChanged, true);
            app.BrigSlider.MinorTicks = [-100 -95 -90 -85 -80 -75 -70 -65 -60 -55 -50 -45 -40 -35 -30 -25 -20 -15 -10 -5 0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.ContSliderLabel = uilabel(app.UIFigure);
            app.ContSliderLabel.Text = 'Cont';
            app.ContSlider = uislider(app.UIFigure);
            app.ContSlider.Limits = [1 10];
            app.ContSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.ContSlider.MajorTickLabels = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.ContSlider.ValueChangedFcn = createCallbackFcn(app, @ContSliderValueChanged, true);
            app.ContSlider.MinorTicks = [1 1.2 1.4 1.6 1.8 2 2.2 2.4 2.6 2.8 3 3.2 3.4 3.6 3.8 4 4.2 4.4 4.6 4.8 5 5.2 5.4 5.6 5.8 6 6.2 6.4 6.6 6.8 7 7.2 7.4 7.6 7.8 8 8.2 8.4 8.6 8.8 9 9.2 9.4 9.6 9.8 10];
            app.ContSlider.Value = 1;
            app.crescentSpinnerLabel = uilabel(app.UIFigure);
            app.crescentSpinnerLabel.Text = 'crescent';
            app.crescentSpinner = uispinner(app.UIFigure);
            app.crescentSpinner.Limits = [1 200];
            app.crescentSpinner.RoundFractionalValues = 'on';
            app.crescentSpinner.Value = 1;
            app.DrawRandomCrescentButton = uibutton(app.UIFigure, 'push');
            app.DrawRandomCrescentButton.ButtonPushedFcn = createCallbackFcn(app, @DrawRandomCrescentButtonPushed, true);
            app.DrawRandomCrescentButton.Text = 'Draw Random Crescent';
            app.MedianFilterButton = uibutton(app.UIFigure, 'state');
            app.MedianFilterButton.ValueChangedFcn = createCallbackFcn(app, @MedianFilterButtonValueChanged, true);
            app.MedianFilterButton.Text = 'Median Filter';
            app.HminSpinnerLabel = uilabel(app.UIFigure);
            app.HminSpinnerLabel.Text = 'Hmin';
            app.HminSpinner = uispinner(app.UIFigure);
            app.HminSpinner.Limits = [0 360];
            app.HminSpinner.ValueChangedFcn = createCallbackFcn(app, @HminSpinnerValueChanged, true);
            app.SminSpinnerLabel = uilabel(app.UIFigure);
            app.SminSpinnerLabel.Text = 'Smin';
            app.SminSpinner = uispinner(app.UIFigure);
            app.SminSpinner.Limits = [0 360];
            app.SminSpinner.ValueChangedFcn = createCallbackFcn(app, @SminSpinnerValueChanged, true);
            app.VminSpinnerLabel = uilabel(app.UIFigure);
            app.VminSpinnerLabel.Text = 'Vmin';
            app.VminSpinner = uispinner(app.UIFigure);
            app.VminSpinner.Limits = [0 360];
            app.VminSpinner.ValueChangedFcn = createCallbackFcn(app, @VminSpinnerValueChanged, true);
            app.HmaxSpinnerLabel = uilabel(app.UIFigure);
            app.HmaxSpinnerLabel.Text = 'Hmax';
            app.HmaxSpinner = uispinner(app.UIFigure);
            app.HmaxSpinner.Limits = [0 360];
            app.HmaxSpinner.ValueChangedFcn = createCallbackFcn(app, @HmaxSpinnerValueChanged, true);
            app.HmaxSpinner.Value = 360;
            app.SmaxSpinnerLabel = uilabel(app.UIFigure);
            app.SmaxSpinnerLabel.Text = 'Smax';
            app.SmaxSpinner = uispinner(app.UIFigure);
            app.SmaxSpinner.Limits = [0 360];
            app.SmaxSpinner.ValueChangedFcn = createCallbackFcn(app, @SmaxSpinnerValueChanged, true);
            app.SmaxSpinner.Value = 360;
            app.VmaxSpinnerLabel = uilabel(app.UIFigure);
            app.VmaxSpinnerLabel.Text = 'Vmax';
            app.VmaxSpinner = uispinner(app.UIFigure);
            app.VmaxSpinner.Limits = [0 360];
            app.VmaxSpinner.ValueChangedFcn = createCallbackFcn(app, @VmaxSpinnerValueChanged, true);
            app.VmaxSpinner.Value = 360;
            app.RSpinnerLabel = uilabel(app.UIFigure);
            app.RSpinnerLabel.Text = 'R';
            app.RSpinner = uispinner(app.UIFigure);
            app.RSpinner.Limits = [0 255];
            app.GSpinnerLabel = uilabel(app.UIFigure);
            app.GSpinnerLabel.Text = 'G';
            app.GSpinner = uispinner(app.UIFigure);
            app.GSpinner.Limits = [0 255];
            app.BSpinnerLabel = uilabel(app.UIFigure);
            app.BSpinnerLabel.Text = 'B';
            app.BSpinner = uispinner(app.UIFigure);
            app.BSpinner.Limits = [0 255];
            app.ChangeColorButton = uibutton(app.UIFigure, 'push');
            app.ChangeColorButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeColorButtonPushed, true);
            app.ChangeColorButton.Enable = 'off';
            app.ChangeColorButton.Text = 'Change Color';
            app.AverageFilterButton = uibutton(app.UIFigure, 'state');
            app.AverageFilterButton.ValueChangedFcn = createCallbackFcn(app, @AverageFilterButtonValueChanged, true);
            app.AverageFilterButton.Text = 'Average Filter';
            app.K1ConvButton = uibutton(app.UIFigure, 'state');
            app.K1ConvButton.ValueChangedFcn = createCallbackFcn(app, @K1ConvButtonValueChanged, true);
            app.K1ConvButton.Text = 'K1 Conv';
            app.HistEqButton = uibutton(app.UIFigure, 'state');
            app.HistEqButton.ValueChangedFcn = createCallbackFcn(app, @HistEqButtonValueChanged, true);
            app.HistEqButton.Text = 'Hist Eq';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageEdit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end